classdef SRT_timeanlaysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        OpenMenu             matlab.ui.container.Menu
        Panel                matlab.ui.container.Panel
        MTVlabel             matlab.ui.control.Label
        CalculateusingupdatedParametersButton  matlab.ui.control.Button
        RTVlabel             matlab.ui.control.Label
        PMTVlabel            matlab.ui.control.Label
        RTlabel              matlab.ui.control.Label
        MTlabel              matlab.ui.control.Label
        PMTlabel             matlab.ui.control.Label
        CommitChangesButton  matlab.ui.control.Button
        ValueofnewthresholdselectedEditField  matlab.ui.control.EditField
        ValueofnewthresholdselectedEditFieldLabel  matlab.ui.control.Label
        NewthresholdselectedattimetEditField  matlab.ui.control.EditField
        NewthresholdselectedattimetEditFieldLabel  matlab.ui.control.Label
        RT                   matlab.ui.control.EditField
        MT                   matlab.ui.control.EditField
        PMT                  matlab.ui.control.EditField
        RTLabel              matlab.ui.control.Label
        MTLabel              matlab.ui.control.Label
        PMTLabel             matlab.ui.control.Label
        TrialBox             matlab.ui.control.ListBox
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            addpath('C:\Ajai\SRT-MT\SRT-MT\dependencies')
            global FileLocation RTFile FiltEmGFile ProcessedTimeCalc
            FileLocation  = uigetdir('','Select file location');
            [~,PartName] = fileparts(FileLocation);
            app.Panel.Title = strcat('Analyzing : ',PartName);
            ProcessedTimeCalc = xlsread(fullfile(FileLocation,'ProcessedTestTimeCalculations.xls'));
            RTFile = xlsread(fullfile(FileLocation,'TestReactionTime.xls'));
            app.TrialBox.Items = getTrials(RTFile);
%             plot(app.UIAxes,FiltEmGFile)
            
            figure(app.UIFigure)
            
        end

        % Value changed function: TrialBox
        function TrialBoxValueChanged(app, event)
            warning('off')
            global TN ProcessedTimeCalc FiltEMG RTFile FileLocation time MaxVLoc SignalData ReacT
            value = app.TrialBox.Value;
            [~,TN] = ismember(value,app.TrialBox.Items);
            %disp(['Trial Number :', num2str(TN)]);
            app.PMT.Value = num2str(ProcessedTimeCalc(TN,1));
            app.MT.Value = num2str(ProcessedTimeCalc(TN,2));
            app.RT.Value = num2str(ProcessedTimeCalc(TN,3));
            RawEMG = xlsread(fullfile(FileLocation,'TestRawData.xls'),TN);
            
            FiltEMG = FilterEMG( PreProcessEMGData( RawEMG(:,2) ) );% signal data
            ReacT = ProcessedTimeCalc(TN,3); 
            PostReactionDAQ = 50;
            skip = 50;
            SignalData = FiltEMG;
            EndDAQ = ReacT*1000 + PostReactionDAQ;
            SDDataTemp = zeros(4000,1);
            SDDataTemp(1:length(SignalData)) = SignalData;
            SignalData = SDDataTemp;
            
            [MaxV,MaxVLoc ] = max(SignalData(skip:EndDAQ));
            [MinV,MinVLoc ] = min(SignalData(skip:EndDAQ));
            time = RawEMG(:,1);
            fsamp = 1000;
            t  = ( (1/fsamp)*100 )*fsamp;
            
            noise = max(SignalData(skip:t));
            threshold = noise + 0.1*noise;
            threshold = threshold + zeros(length(RawEMG),1);
            
            NewThreshold = 0.15*MaxV+threshold + zeros(length(RawEMG),1); 
            % get the value of NewThreshold from plot if empty then assign
            % the calculated value else continue with the new value
            noise = noise + zeros(length(RawEMG),1);
            
            StimStep = zeros(size(time));
            StimStep(time <= ReacT ) = 1.2*MaxV;
            
            lw = 2;
            fs = 20;
            plot(app.UIAxes,time,SignalData(1:length(time)),'LineWidth',lw);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,time,threshold,'LineWidth',lw);
            plot(app.UIAxes,time,NewThreshold,'LineWidth',lw);
            plot(app.UIAxes,time,noise,'LineWidth',lw);
            plot(app.UIAxes,time,StimStep,'LineWidth',lw)
            hold(app.UIAxes,'off')
            title(app.UIAxes,['Trial Number :', num2str(TN)]);
            
%             xlabel('$Time(s)$','Interpreter','latex','FontWeight','bold','FontSize',fs)
%             ylabel('$Signal Amplitude $','Interpreter','latex','FontWeight','bold','FontSize',fs)
            legend(app.UIAxes,{'$Filtered EMG$','$Threshold$','EMG Onset','$Noise$','$Stimulus Step Fn$'},'Interpreter','latex','FontSize',13,'FontWeight','bold')


            i = 0;
            for i = MaxVLoc:-1:1
                if(SignalData(i) < threshold + 0.15*MaxV )
                    EMGthreshold  = i+1 ; % EMGthreshold == EMG Onset
                    break;
                end
            end
            
            EMGOnSetTime = time(EMGthreshold);
            TempTime =  time - ReacT < 1e-7;
            TempTimeIndexVec = find(TempTime == 1);
            TempTimeIndex = TempTimeIndexVec(end);
            PMRT = time(TempTimeIndex) - EMGOnSetTime;


        end

        % Button pushed function: 
        % CalculateusingupdatedParametersButton
        function CalculateusingupdatedParametersButtonPushed(app, event)
            global time MaxVLoc SignalData ReacT NewPMRT NewEMGOnsetTime
           cursor_info = evalin("base",'cursor_info');
           t = cursor_info.Position(1);
           NewEMGTheshold = cursor_info.Position(2);
           app.NewthresholdselectedattimetEditField.Value = num2str(t);
           app.ValueofnewthresholdselectedEditField.Value = num2str(NewEMGTheshold);
           
           NewEMGOnSetIndx = find(time == t);
           NewEMGOnsetTime = time(NewEMGOnSetIndx);
           TempTime = time - ReacT < 1e-7;
           TempTimeIndxVec = find(TempTime ==1);
           TempTimeIndx = TempTimeIndxVec(end);
           
           NewPMRT = time(TempTimeIndx) - NewEMGOnsetTime;
           
           NewRT = ReacT;
           app.PMTVlabel.Text = num2str(NewEMGOnsetTime);
           app.MTVlabel.Text = num2str(NewPMRT);
           app.RTVlabel.Text = num2str(NewRT);
           
           
        end

        % Button pushed function: CommitChangesButton
        function CommitChangesButtonPushed(app, event)
            global ProcessedTimeCalc ReacT NewPMRT NewEMGOnsetTime TN FileLocation
            
            [icondata,iconcmap] = imread("success.svg"); 
            
            temp = ProcessedTimeCalc;
            temp(TN,1) = NewEMGOnsetTime;
            temp(TN,2) = NewPMRT;
            temp(TN,3) = ReacT;
            TempTable = array2table(temp,'VariableNames',{'PMT','MT','RT'});
            writetable(TempTable,fullfile(FileLocation,'ModifiedTimeCalculations.xls'));
            
            if( isfile(fullfile(FileLocation,'ModifiedTimeCalculations.xls')) )
                msgbox("New File Created ","Success","custom",icondata,iconcmap);
            else
                errordlg('Unable to create file','File Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, 'Time (X)')
            ylabel(app.UIAxes, 'Singal Amplitude (Y)')
            zlabel(app.UIAxes, 'Z')
            app.TrialBox = uilistbox(app.Panel);
            app.TrialBox.Items = {};
            app.TrialBox.ValueChangedFcn = createCallbackFcn(app, @TrialBoxValueChanged, true);
            app.TrialBox.Value = {};
            app.PMTLabel = uilabel(app.Panel);
            app.PMTLabel.Text = 'PMT';
            app.MTLabel = uilabel(app.Panel);
            app.MTLabel.Text = 'MT';
            app.RTLabel = uilabel(app.Panel);
            app.RTLabel.Text = 'RT';
            app.PMT = uieditfield(app.Panel, 'text');
            app.PMT.Editable = 'off';
            app.MT = uieditfield(app.Panel, 'text');
            app.MT.Editable = 'off';
            app.RT = uieditfield(app.Panel, 'text');
            app.RT.Editable = 'off';
            app.NewthresholdselectedattimetEditFieldLabel = uilabel(app.Panel);
            app.NewthresholdselectedattimetEditFieldLabel.Text = {'New threshold selected at time t  =  '; ''};
            app.NewthresholdselectedattimetEditField = uieditfield(app.Panel, 'text');
            app.NewthresholdselectedattimetEditField.Editable = 'off';
            app.ValueofnewthresholdselectedEditFieldLabel = uilabel(app.Panel);
            app.ValueofnewthresholdselectedEditFieldLabel.Text = {'Value of new threshold selected  = '; ''};
            app.ValueofnewthresholdselectedEditField = uieditfield(app.Panel, 'text');
            app.ValueofnewthresholdselectedEditField.Editable = 'off';
            app.CommitChangesButton = uibutton(app.Panel, 'push');
            app.CommitChangesButton.ButtonPushedFcn = createCallbackFcn(app, @CommitChangesButtonPushed, true);
            app.CommitChangesButton.Text = 'Commit Changes';
            app.PMTlabel = uilabel(app.Panel);
            app.PMTlabel.Text = 'New PMT';
            app.MTlabel = uilabel(app.Panel);
            app.MTlabel.Text = 'New MT';
            app.RTlabel = uilabel(app.Panel);
            app.RTlabel.Text = 'New RT';
            app.PMTVlabel = uilabel(app.Panel);
            app.PMTVlabel.Text = '';
            app.RTVlabel = uilabel(app.Panel);
            app.RTVlabel.Text = '';
            app.CalculateusingupdatedParametersButton = uibutton(app.Panel, 'push');
            app.CalculateusingupdatedParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateusingupdatedParametersButtonPushed, true);
            app.CalculateusingupdatedParametersButton.Text = 'Calculate using updated Parameters';
            app.MTVlabel = uilabel(app.Panel);
            app.MTVlabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SRT_timeanlaysis

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end